classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HELPButton                    matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        Info                          matlab.ui.control.TextArea
        NumeratorEditField            matlab.ui.control.EditField
        NumeratorEditFieldLabel       matlab.ui.control.Label
        DenominatorEditField          matlab.ui.control.EditField
        DenominatorLabel              matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        SystemStabilityAnalysisLabel  matlab.ui.control.Label
        AnalysisButton                matlab.ui.control.Button
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        Locus                         matlab.ui.control.UIAxes
        Step                          matlab.ui.control.UIAxes
        GainPhase                     matlab.ui.control.UIAxes
        Bode                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tf_sys % transfer function of the system
        freq   % frequency range
    end
    
    methods (Access = private)
        
        % Custom Bode Function
        function [w,mag, phase] = customBode(app)
            w = logspace(log10(app.freq(1)), log10(app.freq(2)), 100);
            mag = [];
            phase = [];
            for i = 1:length(w)
                omega = 2 * pi * w(i);
                H = evalfr(app.tf_sys, 1j * omega);
                mag(i) = 20 * log10(abs(H));
                phase(i) = rad2deg(angle(H));
            end
        end

        function plotCustomBode(app)
            [w, mag,phase] = customBode(app);

            cla(app.Bode, 'reset');
            yyaxis(app.Bode, 'left');
            semilogx(app.Bode, w, mag, 'b-', 'LineWidth', 1.5);
            ylabel(app.Bode, 'Magnitude (dB)');

            yyaxis(app.Bode, 'right');
            semilogx(app.Bode, w, phase, 'r--', 'LineWidth', 1.5);
            ylabel(app.Bode, 'Phase (deg)');

            xlabel(app.Bode, 'Frequency (Hz)');
            title(app.Bode, 'Custom Bode Plot');
            grid(app.Bode, 'on');
        end

        % Plot gain and phase margin
        function plotGainPhaseMargin(app)
            [mag, phase, w] = customBode(app);
            %mag = squeeze(mag);
            %phase = squeeze(phase);

            cla(app.GainPhase, 'reset');
            yyaxis(app.GainPhase, 'left');
            semilogx(app.GainPhase, w/(2*pi), 20*log10(mag), 'b-', 'LineWidth', 1.5);
            ylabel(app.GainPhase, 'Magnitude (dB)');

            yyaxis(app.GainPhase, 'right');
            semilogx(app.GainPhase, w/(2*pi), phase, 'r-', 'LineWidth', 1.5);
            ylabel(app.GainPhase, 'Phase (deg)');

            xlabel(app.GainPhase, 'Frequency (Hz)');
            title(app.GainPhase, 'Gain and Phase Margin');
            grid(app.GainPhase, 'on');

            [gm, pm, ~, ~] = margin(app.tf_sys);
            text(app.GainPhase, w(1)/(2*pi), max(20*log10(mag)), sprintf('GM: %.2f dB\nPM: %.2f deg', 20*log10(gm), pm), ...
                'FontSize', 8, 'Color', 'black');
        end

        % Plot root locus
        function plotRootLocus(app)
            cla(app.Locus, 'reset');
            [r, kout] = rlocus(app.tf_sys);
            hold(app.Locus, 'on');

            for i = 1:size(r, 1)
                plot(app.Locus, real(r(i, :)), imag(r(i, :)), 'b-', 'LineWidth', 1.5);
            end
        
            [p, ~] = pzmap(app.tf_sys);
            plot(app.Locus, real(p), imag(p), 'rx', 'MarkerSize', 8, 'LineWidth', 2);
        
            [~, z] = pzmap(app.tf_sys);
            if ~isempty(z)
                plot(app.Locus, real(z), imag(z), 'go', 'MarkerSize', 8, 'LineWidth', 2);
            end
        
            grid(app.Locus, 'on');
            xlabel(app.Locus, 'Real Axis');
            ylabel(app.Locus, 'Imaginary Axis');
            title(app.Locus, 'Root Locus Plot');
            axis(app.Locus, 'equal');
        
            for i = 1:size(r, 1)
                text(app.Locus, real(r(i, end)), imag(r(i, end)), sprintf('k=%.2f', kout(end)), ...
                    'FontSize', 8, 'Color', 'blue', 'HorizontalAlignment', 'left');
            end
        
            hold(app.Locus, 'off');
        end

        % Display system information
        function displaySystemInfo(app)
            if ~isstable(app.tf_sys)
                app.Info.Value = {'Warning: System is unstable'};
                return;
            end

            poles = pole(app.tf_sys);
            zeros_sys = zero(app.tf_sys);
            info = stepinfo(app.tf_sys);
            [gm, pm, ~, ~] = margin(app.tf_sys);

            system_info = sprintf(['System Information:\n' ...
                'Poles: %s\n' ...
                'Zeros: %s\n' ...
                'Rise Time: %.2f s\n' ...
                'Settling Time: %.2f s\n' ...
                'Percent Overshoot: %.2f%%\n' ...
                'Gain Margin: %.2f dB\n' ...
                'Phase Margin: %.2f deg'], ...
                mat2str(poles, 3), mat2str(zeros_sys, 3), ...
                info.RiseTime, info.SettlingTime, info.Overshoot, ...
                20*log10(gm), pm);
            
            app.Info.Value = splitlines(system_info);
        end

        % Plot step response
        function plotStepResponse(app)
            cla(app.Step, 'reset');
            try
                [y, t] = step(app.tf_sys);
                plot(app.Step, t, y, 'b-', 'LineWidth', 1.5);
                xlabel(app.Step, 'Time (s)');
                ylabel(app.Step, 'Amplitude');
                title(app.Step, 'Step Response');
                grid(app.Step, 'on');
            catch e
                warndlg(sprintf('Error computing step response: %s', e.message), 'Step Response Error');
            end
        end

        % Create transfer function from inputs
        function [success, err_msg] = createTransferFunction(app, in_num, in_den, in_frq_lo, in_frq_hi)
            success = true;
            err_msg = '';

            try
                % Replace commas with spaces
                in_num = strrep(in_num, ',', ' ');
                in_den = strrep(in_den, ',', ' ');
                
                % Convert text input to numeric vectors
                num = str2num(in_num);
                den = str2num(in_den);

                % Validate inputs
                if isempty(num) || isempty(den)
                    success = false;
                    err_msg = 'Please enter valid numbers separated by spaces or commas!';
                    return;
                end
                
                if isempty(in_frq_lo) || isempty(in_frq_hi) || in_frq_lo >= in_frq_hi
                    success = false;
                    err_msg = 'Please enter a valid frequency range!';
                    return;
                end

                % Create transfer function
                app.tf_sys = tf(num, den);
            catch
                success = false;
                err_msg = 'Error parsing the input. Use format "1 2 3"';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
                % Get and validate inputs
            in_num = app.NumeratorEditField.Value;
            in_den = app.DenominatorEditField.Value;
            in_frq_lo = app.FrequencyEditField.Value;
            in_frq_hi = app.EditField.Value;
            app.freq = [in_frq_lo, in_frq_hi];

            % Convert inputs to transfer function
            [success, err_msg] = app.createTransferFunction(in_num, in_den, in_frq_lo, in_frq_hi);
            if ~success
                warndlg(err_msg, 'Input Error');
                return;
            end

            % Check system stability
            if ~isstable(app.tf_sys)
                warndlg('System is unstable. Some analyses may not be valid.', 'Stability Warning');
            end

            % Perform analyses
            app.plotCustomBode();
            app.plotGainPhaseMargin();
            app.plotRootLocus();
            app.displaySystemInfo();
            app.plotStepResponse();   
        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            url = 'https://github.com/jbarber877/Bode';
            web(url)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Bode = uiaxes(app.UIFigure);
            title(app.Bode, 'Bode Plot')
            xlabel(app.Bode, 'X')
            ylabel(app.Bode, 'Y')
            zlabel(app.Bode, 'Z')
            app.GainPhase = uiaxes(app.UIFigure);
            title(app.GainPhase, 'Gain and Phase Margin')
            xlabel(app.GainPhase, 'X')
            ylabel(app.GainPhase, 'Y')
            zlabel(app.GainPhase, 'Z')
            app.Step = uiaxes(app.UIFigure);
            title(app.Step, 'Step Response')
            xlabel(app.Step, 'X')
            ylabel(app.Step, 'Y')
            zlabel(app.Step, 'Z')
            app.Locus = uiaxes(app.UIFigure);
            title(app.Locus, 'Root Locus')
            xlabel(app.Locus, 'X')
            ylabel(app.Locus, 'Y')
            zlabel(app.Locus, 'Z')
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 10;
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.SystemStabilityAnalysisLabel = uilabel(app.UIFigure);
            app.SystemStabilityAnalysisLabel.Text = 'System Stability Analysis';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Enter the transfer function coefficients and frequency range:'; '1. Numerator: Enter coefficients (e.g., "1" for 1, "1 2" for s+2).'; '2. Denominator: Enter coefficients (e.g., "1 2 1" for s²+2s+1).'; '3. Frequency Range: Enter start and end frequencies (e.g., 0.1 to 1000 Hz).'; 'Click ''Analysis'' to plot the Bode plot, showing magnitude (dB) and phase (deg) vs. frequency (Hz).'; 'The custom bode function will use logspace to create evenly spaced sampling points in the frequency range on which the function can be evaluated, and the output plotted. The bode plot compares changes in the magnitude and phase of the response, which are shown in decibels and radians.'; ''; 'For detailed instructions, see HELP'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1000;
            app.DenominatorLabel = uilabel(app.UIFigure);
            app.DenominatorLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominatorEditField.Value = '1 2 3';
            app.NumeratorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.UIFigure, 'text');
            app.NumeratorEditField.Value = '1';
            app.Info = uitextarea(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'System Stability Analysis'; ''; 'Input the numerator and denominator coefficients of a transfer function to analyze stability characteristics. This tool generates Bode plots with gain/phase margins, root locus diagrams, step response plots, and system information to help evaluate your control system''s performance and stability.'};
            app.HELPButton = uibutton(app.UIFigure, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Text = 'HELP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end